classdef CashierUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CashierUIFigure         matlab.ui.Figure
        BarcodeEditFieldLabel   matlab.ui.control.Label
        BarcodeEditField        matlab.ui.control.NumericEditField
        QuantityEditFieldLabel  matlab.ui.control.Label
        QuantityEditField       matlab.ui.control.NumericEditField
        AddButton               matlab.ui.control.Button
        RemoveButton            matlab.ui.control.Button
        CheckTable              matlab.ui.control.Table
        CollectMoneyButton      matlab.ui.control.Button
        AggregatePanel          matlab.ui.container.Panel
        AggregateLabel          matlab.ui.control.Label
        StartCheckOutButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        database Database 
    end
    
    methods (Access = private)
        
        function updateAggregate(app)
            displayData = app.CheckTable.Data;
            
            items = size(displayData,1);
            money = dot(cell2mat(displayData(:,3)),cell2mat(displayData(:,4)));
            temptext = sprintf('Total items      %0.f\n\nTotal money       %.2f',items,money);
            app.AggregateLabel.Text = temptext;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AddButton.Enable = false;
            app.RemoveButton.Enable = false;
            app.BarcodeEditField.Enable = false;
            app.QuantityEditField.Enable = false;
            app.CollectMoneyButton.Enable = false;
            app.AggregatePanel.Visible = false;
        end

        % Button pushed function: StartCheckOutButton
        function StartCheckOutButtonPushed(app, event)
            app.BarcodeEditField.Enable = true;
            app.QuantityEditField.Enable = true;
            app.AddButton.Enable = true;
            app.StartCheckOutButton.Enable = false;
            app.CollectMoneyButton.Enable = true;
            app.AggregatePanel.Visible = true;
            temptext = sprintf('Total items       0\n\nTotal money       0');
            app.AggregateLabel.Text = temptext;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            db = app.database;
            barcode = app.BarcodeEditField.Value;
            quantity = app.QuantityEditField.Value;
            tempdata = app.CheckTable.Data;
            if db.getQuantity(barcode) >= quantity
                name = db.getName(barcode);
                price = db.getPrice(barcode);
                if quantity
                    if isempty(tempdata)
                        tempdata = {int2str(barcode),name,quantity,price};
                    else
                        sameind = find(contains(tempdata(:,1),int2str(barcode)),1);
                        if isempty(sameind)
                            tempdata = [tempdata;{int2str(barcode),name,quantity,price}];
                        else
                            tempdata{sameind,3} = tempdata{sameind,3} + quantity;
                            if tempdata{sameind,3} > db.getQuantity(barcode)
                                tempdata{sameind,3} = double(db.getQuantity(barcode));
                            end
                        end
                    end
                end
                app.CheckTable.Data = tempdata;
            end
            
            app.RemoveButton.Enable = true;
            
            if isempty(app.CheckTable.Data)
                app.RemoveButton.Enable = false;
                temptext = sprintf('Total items       0\n\nTotal money       0');
                app.AggregateLabel.Text = temptext;
            else
                updateAggregate(app);
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            barcode = app.BarcodeEditField.Value;
            quantity = app.QuantityEditField.Value;
            tempdata = app.CheckTable.Data;
            
            sameind = find(contains(tempdata(:,1),int2str(barcode)),1);
            if ~isempty(sameind)
                tempdata{sameind,3} = tempdata{sameind,3} - quantity;
                if tempdata{sameind,3} <= 0
                    tempdata(sameind,:) = [];
                end
            end
            app.CheckTable.Data = tempdata;
            
            if isempty(app.CheckTable.Data)
                app.RemoveButton.Enable = false;
                temptext = sprintf('Total items       0\n\nTotal money       0');
                app.AggregateLabel.Text = temptext;
            else
                updateAggregate(app);
            end
        end

        % Button pushed function: CollectMoneyButton
        function CollectMoneyButtonPushed(app, event)
            if ~isempty(app.CheckTable.Data)
                app.CashierUIFigure.Visible = false;
                pui = PaymentUI;
                pui.cashierUI = app;
                temptext = sprintf('Items----------------\n\n    Name     Unit Price     Quantity     Total Price');
                checkData = app.CheckTable.Data;
                for i = 1:size(checkData,1)
                    temptext = [temptext,sprintf('\n    %s     %.2f     %11.4g     %.2f',checkData{i,2},checkData{i,4},checkData{i,3},checkData{i,4}*checkData{i,3})];
                end
                    temptext = [temptext,sprintf('\n\nAggregate----------------')];
                    
                    items = size(checkData,1);
                    money = dot(cell2mat(checkData(:,3)),cell2mat(checkData(:,4)));
                    temptext = [temptext,sprintf('\n\n    Total items      %0.f\n    Total money       %.2f',items,money)];
                    
                pui.TextArea.Value = temptext;
                pui.money = money;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CashierUIFigure = uifigure('Visible', 'off');
            app.CashierUIFigure.Name = 'CashierUI';
            app.BarcodeEditFieldLabel = uilabel(app.CashierUIFigure);
            app.BarcodeEditFieldLabel.Text = 'Barcode';
            app.BarcodeEditField = uieditfield(app.CashierUIFigure, 'numeric');
            app.BarcodeEditField.Limits = [0 Inf];
            app.BarcodeEditField.ValueDisplayFormat = '%.0f';
            app.QuantityEditFieldLabel = uilabel(app.CashierUIFigure);
            app.QuantityEditFieldLabel.Text = 'Quantity';
            app.QuantityEditField = uieditfield(app.CashierUIFigure, 'numeric');
            app.QuantityEditField.Limits = [0 Inf];
            app.AddButton = uibutton(app.CashierUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '>>';
            app.RemoveButton = uibutton(app.CashierUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<<';
            app.CheckTable = uitable(app.CashierUIFigure);
            app.CheckTable.ColumnName = {'Barcode'; 'Name'; 'Quantity'; 'Unit Price'};
            app.CheckTable.RowName = {};
            app.CheckTable.ColumnSortable = false;
            app.CheckTable.ColumnEditable = false;
            app.CollectMoneyButton = uibutton(app.CashierUIFigure, 'push');
            app.CollectMoneyButton.ButtonPushedFcn = createCallbackFcn(app, @CollectMoneyButtonPushed, true);
            app.CollectMoneyButton.Text = 'Collect Money';
            app.AggregatePanel = uipanel(app.CashierUIFigure);
            app.AggregatePanel.Title = 'Aggregate';
            app.AggregateLabel = uilabel(app.AggregatePanel);
            app.AggregateLabel.Text = '';
            app.StartCheckOutButton = uibutton(app.CashierUIFigure, 'push');
            app.StartCheckOutButton.ButtonPushedFcn = createCallbackFcn(app, @StartCheckOutButtonPushed, true);
            app.StartCheckOutButton.Text = 'Start Check Out';
            % Show the figure after all components are created
            app.CashierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CashierUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CashierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CashierUIFigure)
        end
    end
end