classdef ManagerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManagerUIFigure            matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        PutinTab                   matlab.ui.container.Tab
        BarcodeLabel               matlab.ui.control.Label
        BarcodeEditField           matlab.ui.control.NumericEditField
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        QuantityLabel              matlab.ui.control.Label
        QuantityEditField          matlab.ui.control.NumericEditField
        CostEditFieldLabel         matlab.ui.control.Label
        CostEditField              matlab.ui.control.NumericEditField
        PriceEditFieldLabel        matlab.ui.control.Label
        PriceEditField             matlab.ui.control.NumericEditField
        UnitEditFieldLabel         matlab.ui.control.Label
        UnitEditField              matlab.ui.control.EditField
        PutinButton                matlab.ui.control.Button
        ClearButton                matlab.ui.control.Button
        EditQuantityTab            matlab.ui.container.Tab
        BarcodeLabel_2             matlab.ui.control.Label
        BarcodeEditField_2         matlab.ui.control.NumericEditField
        QuantityLabel_2            matlab.ui.control.Label
        QuantityEditField_2        matlab.ui.control.NumericEditField
        EditButton                 matlab.ui.control.Button
        EditInfoTab                matlab.ui.container.Tab
        BarcodeEditField_3Label    matlab.ui.control.Label
        BarcodeEditField_edit      matlab.ui.control.NumericEditField
        NameEditField_2Label       matlab.ui.control.Label
        NameEditField_edit         matlab.ui.control.EditField
        PriceEditField_2Label      matlab.ui.control.Label
        PriceEditField_edit        matlab.ui.control.NumericEditField
        UnitEditField_2Label       matlab.ui.control.Label
        UnitEditField_edit         matlab.ui.control.EditField
        EditButton_edit            matlab.ui.control.Button
        ClearButton_edit           matlab.ui.control.Button
        GenerateReportTab          matlab.ui.container.Tab
        StartDateDatePickerLabel   matlab.ui.control.Label
        StartDateDatePicker        matlab.ui.control.DatePicker
        EndDateDatePickerLabel     matlab.ui.control.Label
        EndDateDatePicker          matlab.ui.control.DatePicker
        TendencyChartButton        matlab.ui.control.Button
        FinancialReportButton      matlab.ui.control.Button
        BarcodeEditField_3Label_2  matlab.ui.control.Label
        BarcodeEditField_genr      matlab.ui.control.NumericEditField
        MerchandiseTable           matlab.ui.control.Table
        MessagePanel               matlab.ui.container.Panel
        ConfirmButton              matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        MessageLabel               matlab.ui.control.Label
        RefreshStorageButton       matlab.ui.control.Button
        chartPanel                 matlab.ui.container.Panel
        tendencyUIAxes             matlab.ui.control.UIAxes
        OKButton                   matlab.ui.control.Button
        quantityUIAxes             matlab.ui.control.UIAxes
        reportPanel                matlab.ui.container.Panel
        reportLabel                matlab.ui.control.Label
    end

    properties (Access = public)
        database Database % basic database
        tab = 0
    end
    
    methods (Access = private)
        
        function showMerchandiseDB(app)
            % need to be improved
            db = app.database;
            app.MerchandiseTable.Data = [];
            
            for i=1:length(db.merchandiseList)
                tempdata = db.merchandiseList(i);
                app.MerchandiseTable.Data = [app.MerchandiseTable.Data;{int2str(tempdata.barcode),tempdata.name,int2str(tempdata.stockQuantity),num2str(tempdata.cost),num2str(tempdata.price),tempdata.unit}];
            end
        end
        
        function result = checkQuantityInput(app,barcode,quantity)
            % check if input quantity is valid
            stockQuantity = app.database.getQuantity(barcode);
            if stockQuantity + quantity < 0 
                result = 0;
            else
                result = 1;
            end
        end
        
        
        function changeEnableStates(app,state)
            % used for confirming, to change the states of other parts
            app.TabGroup.Visible = state;
            app.RefreshStorageButton.Visible = state;
            if state
                app.MerchandiseTable.Enable = 'on';
            else
                app.MerchandiseTable.Enable = 'off';
            end     
            app.ConfirmButton.Enable = ~state;
            app.CancelButton.Enable = ~state;
        end
        
        function [name,cost,price,unit] = autoFillInput(app,barcode,name,cost,price,unit)
            % if input is empty, fill the data needed with values in db
            db = app.database;
            if strcmp(name,'') 
                name = db.getName(barcode); 
            end
            if cost == 0 
                cost = db.getCost(barcode); 
            end
            if strcmp(unit,'') 
                unit = db.getUnit(barcode); 
            end
            if price == 0 
                price = db.getPrice(barcode); 
            end
        end
        
        function [times,values] = getDuringData(~,barcode,st,et,list,event,mode)
            % find data in the change lists with some requirement
            % mode 0 doesn't care event but cares barcode
            % mode 1 doesn't care barcode but cares event
            % mode 2 is the same as mode 1, but values are increments
            times = [];
            values = [];
            switch mode
                case 0
                    for i = 1:length(list)
                        temptime = list(i).time;
                        tempvalue = list(i).num;
                        tempbc = list(i).barcode;
                        if (temptime >= st) && (temptime <= et) && (tempbc == barcode)
                            times = [times, temptime];
                            values = [values, tempvalue];   
                        end
                    end
                    
                case 1
                    for i = 1:length(list)
                        temptime = list(i).time;
                        tempvalue = list(i).num;
                        tempevent = list(i).event;
                        if (temptime >= st) && (temptime <= et) && (tempevent == event)
                            times = [times, temptime];
                            values = [values, tempvalue]; 
                        end
                    end
                    
                case 2
                    for i = 1:length(list)
                        temptime = list(i).time;
                        tempvalue = list(i).num;
                        tempevent = list(i).event;
                        tempbc = list(i).barcode;
                        if (temptime >= st) && (temptime <= et) && (tempevent == event)
                            times = [times, temptime];
                            prevalue = 0;
                            if i > 1
                                for k = (i-1):-1:1
                                    prevbc = list(k).barcode;
                                    if prevbc == tempbc
                                        prevalue = list(k).num;
                                        break;
                                    end
                                end                  
                            end
                            tempvalue = tempvalue - prevalue;
                            values = [values, tempvalue]; 
                        end
                    end
                    
            end
            
        end
        
        function [times,values] = getDuringSales(app,barcode,st,et)
            % find data in the sales record with some requirement
            times = [];
            values = [];
            list = app.database.salesRecordList;
            for i = 1:length(list)
                temptime = list(i).time;
                tempbc = list(i).barcode;
                if (temptime >= st) && (temptime <= et) && (tempbc == barcode)
                    times = [times, temptime];
                    values = [values,list(i).quantity];
                end
            end
        end
        
        function turnover = getDuringTurnover(app,st,et)
            % calculate turnover in a certain time interval
            turnover = 0;
            list = app.database.salesRecordList;
            for i = 1:length(list)
                temptime = list(i).time;
                if (temptime >= st) && (temptime <= et)
                    turnover = turnover + list(i).price * list(i).quantity;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartDateDatePicker.Value = datetime('now','Format','uuuu-MM-dd');
            app.EndDateDatePicker.Value = datetime('now','Format','uuuu-MM-dd');
        end

        % Button pushed function: PutinButton
        function PutinButtonPushed(app, event)
            db = app.database;
            barcode = app.BarcodeEditField.Value;
            name = app.NameEditField.Value;
            quantity = app.QuantityEditField.Value;
            cost = app.CostEditField.Value;
            price = app.PriceEditField.Value;
            unit = app.UnitEditField.Value;
            
            if quantity 
                app.tab = 1;
                changeEnableStates(app,false);
    
                if db.getQuantity(barcode) >= 0
                    app.MessageLabel.Text = sprintf('Increase quantity: Barcode %0.f    Number of increase %f\n',barcode,quantity);
                    [name,cost,price,unit] = autoFillInput(app,barcode,name,cost,price,unit);
                    app.MessageLabel.Text = [app.MessageLabel.Text, sprintf('New info: Name %s    Cost %.2f    Price %.2f    Unit %s\nPlease check.',name,cost,price,unit)];
                else
                    app.MessageLabel.Text = sprintf('New merchandise: Barcode %0.f    Quantity %f    Unit %s\n                               Name %s    Cost %.2f    Price %.2f\nPlease check.',barcode,quantity,unit,name,cost,price);
                end
            else
                app.MessageLabel.Text = sprintf('Wrong quantity input!\nPlease try again.');
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.BarcodeEditField.Value = 0;
            app.NameEditField.Value = '';
            app.QuantityEditField.Value = 0;
            app.CostEditField.Value = 0;
            app.PriceEditField.Value = 0;
            app.UnitEditField.Value = '';
            
            app.MessageLabel.Text = 'All edit fields have been cleared.';
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            db = app.database;
            barcode = app.BarcodeEditField_2.Value;
            quantity = app.QuantityEditField_2.Value;
            
            unit = db.getUnit(barcode);
            if quantity && checkQuantityInput(app,barcode,quantity) && db.getQuantity(barcode) ~= -1
                db.editQuantity(barcode,quantity,MerchandiseEvent.editQuantity);
                showMerchandiseDB(app);
                if quantity<0
                    app.MessageLabel.Text = sprintf('Decrease quantity: Barcode %0.f    \nNumber of decrease %f    Unit %s\n',barcode,-quantity,unit);
                elseif quantity>0
                    app.MessageLabel.Text = sprintf('Increase quantity: Barcode %0.f    \nNumber of increase %f    Unit %s\n',barcode,quantity,unit);
                end
            else
                app.MessageLabel.Text = sprintf('Wrong barcode or quantity input!\nPlease try again.');
            end
        end

        % Button pushed function: RefreshStorageButton
        function RefreshStorageButtonPushed(app, event)
            showMerchandiseDB(app);
            app.MessageLabel.Text = 'Storage has been refreshed.';
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            db = app.database;
            if app.tab == 1
                barcode = app.BarcodeEditField.Value;
                name = app.NameEditField.Value;
                quantity = app.QuantityEditField.Value;
                cost = app.CostEditField.Value;
                price = app.PriceEditField.Value;
                unit = app.UnitEditField.Value;
                
                if db.getQuantity(barcode) >= 0
                    [name,cost,price,unit] = autoFillInput(app,barcode,name,cost,price,unit);      
                end
                
                db.addMerchandise(barcode,name,quantity,cost,price,unit);
                
            elseif app.tab == 3
                barcode = app.BarcodeEditField_edit.Value;
                name = app.NameEditField_edit.Value;
                price = app.PriceEditField_edit.Value;
                unit = app.UnitEditField_edit.Value;
                cost = 0;
                
                [name,cost,price,unit] = autoFillInput(app,barcode,name,cost,price,unit);
                db.editInfo(barcode,name,cost,price,unit);
  
            elseif app.tab == 4
                changeEnableStates(app,true);
                app.reportPanel.Visible = false;
                app.MessageLabel.Text = 'Showing financial report... done.';
                return;
            end

            showMerchandiseDB(app);
            changeEnableStates(app,true);
            app.MessageLabel.Text = 'The operation has been confirmed.';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            changeEnableStates(app,true);
            app.MessageLabel.Text = 'The operation has been cancelled.';
        end

        % Button pushed function: ClearButton_edit
        function ClearButton_editPushed(app, event)
            app.BarcodeEditField_edit.Value = 0;
            app.NameEditField_edit.Value = '';
            app.PriceEditField_edit.Value = 0;
            app.UnitEditField_edit.Value = '';
            
            app.MessageLabel.Text = 'All edit fields have been cleared.';
        end

        % Button pushed function: EditButton_edit
        function EditButton_editPushed(app, event)
            barcode = app.BarcodeEditField_edit.Value;
            name = app.NameEditField_edit.Value;
            price = app.PriceEditField_edit.Value;
            unit = app.UnitEditField_edit.Value;
            cost = 0;
            
            if checkQuantityInput(app,barcode,0)
                app.tab = 3;
                changeEnableStates(app,false);

                app.MessageLabel.Text = sprintf('Merchandise: Barcode %0.f\n',barcode);
                [name,~,price,unit] = autoFillInput(app,barcode,name,cost,price,unit);
                app.MessageLabel.Text = [app.MessageLabel.Text, sprintf('New info: Name %s    Price %.2f    Unit %s\nPlease check.',name,price,unit)];
            
            else
                app.MessageLabel.Text = sprintf('Wrong barcode input!\nPlease try again.');
            end
        end

        % Cell selection callback: MerchandiseTable
        function MerchandiseTableCellSelection(app, event)
            indices = event.Indices;
            
            barcode = app.MerchandiseTable.Data{indices(1),1};
            barcode = str2double(barcode);
            app.BarcodeEditField.Value = barcode;
            app.BarcodeEditField_2.Value = barcode;
            app.BarcodeEditField_edit.Value = barcode;
            app.BarcodeEditField_genr.Value = barcode;
        end

        % Button pushed function: TendencyChartButton
        function TendencyChartButtonPushed(app, event)
            startTime = app.StartDateDatePicker.Value;
            endTime = app.EndDateDatePicker.Value;
            startTime.TimeZone = "local";
            endTime.TimeZone = "local";
            barcode = app.BarcodeEditField_genr.Value;
            db = app.database;
            
            [costtimes, costvalues] = app.getDuringData(barcode,startTime,endTime,db.costChangeList,0,0);
            [pricetimes, pricevalues] = app.getDuringData(barcode,startTime,endTime,db.priceChangeList,0,0);
            [profittimes, profitvalues] = app.getDuringData(barcode,startTime,endTime,db.profitChangeList,0,0);
            
            [amounttimes, amountvalues] = app.getDuringData(barcode,startTime,endTime,db.amountChangeList,0,0);
            [salestimes, salesquantities] = app.getDuringSales(barcode,startTime,endTime);
            
            if ~isempty(costtimes) || ~isempty(pricetimes) || ~isempty(profittimes)
                app.MessageLabel.Text = 'Showing tendency chart...';
                % for money 
                title(app.tendencyUIAxes, ['Merchandise barcode: ', num2str(barcode)]);
                xlabel(app.tendencyUIAxes,'Time');
                ylabel(app.tendencyUIAxes,'Money');
                axis(app.tendencyUIAxes,'auto');
                plot(app.tendencyUIAxes,costtimes,costvalues,'Marker',"o");
                
                hold(app.tendencyUIAxes,'on')
                
                plot(app.tendencyUIAxes,pricetimes,pricevalues,'Marker',"o");
                hold(app.tendencyUIAxes,'on')
                
                plot(app.tendencyUIAxes,profittimes,profitvalues,'Marker',"o");
                legend(app.tendencyUIAxes,'Cost','Price','Profit');
                legend(app.tendencyUIAxes,'boxoff')
                hold(app.tendencyUIAxes,'off')
                
                % for amount
                xlabel(app.quantityUIAxes,'Time');
                ylabel(app.quantityUIAxes,'Quantity');
                axis(app.quantityUIAxes,'auto');
                yticks(app.quantityUIAxes,'auto');
                
                plot(app.quantityUIAxes,amounttimes,amountvalues,'Marker',"o");
                hold(app.quantityUIAxes,'on')
                
                plot(app.quantityUIAxes,salestimes,salesquantities,'Marker',"o");
                legend(app.quantityUIAxes,'Stock Quantity','Sales Quantity');
                legend(app.quantityUIAxes,'boxoff')
                hold(app.quantityUIAxes,'off')
   
                app.chartPanel.Visible = true;
                app.TabGroup.Visible = false;
            else
                app.MessageLabel.Text = sprintf('Record not found!\nPlease try again.');
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.chartPanel.Visible = false;
            app.TabGroup.Visible = true;
            app.MessageLabel.Text = 'Showing tendency chart... done.';
        end

        % Close request function: ManagerUIFigure
        function ManagerUIFigureCloseRequest(app, event)
            db = app.database;
            if ~isempty(db)
                save('db_1.mat',"db"); 
            end
            delete(app)    
        end

        % Button pushed function: FinancialReportButton
        function FinancialReportButtonPushed(app, event)
            startTime = app.StartDateDatePicker.Value;
            endTime = app.EndDateDatePicker.Value;
            startTime.TimeZone = "local";
            endTime.TimeZone = "local";
            db = app.database;
            
            [~,costvalues] = getDuringData(app,0,startTime,endTime,db.costChangeList,MerchandiseEvent.putIn,1);
            [~,quantityvalues] = getDuringData(app,0,startTime,endTime,db.amountChangeList,MerchandiseEvent.putIn,2);
            cost = dot(costvalues,quantityvalues); % total cost
            turnover = getDuringTurnover(app,startTime,endTime);
            profit = turnover - cost;
            
            app.tab = 4;
            
            temptext = sprintf('------------------------------\nFINANCIAL REPORT\n------------------------------\n\n');
            app.reportLabel.Text = temptext;
            temptext = sprintf('From %s to %s:\n\n\n',datestr(startTime,'yyyy-mm-dd'),datestr(endTime,'yyyy-mm-dd'));
            app.reportLabel.Text = [app.reportLabel.Text,temptext];
            
            temptext = sprintf('Total Cost: %.2f\n\nTotal Turnover: %.2f\n\nTotal Profit: %.2f\n', cost,turnover,profit);
            app.reportLabel.Text = [app.reportLabel.Text,temptext];
            
            app.reportPanel.Visible = true;
            app.changeEnableStates(false);
            app.CancelButton.Enable = false;
            app.MessageLabel.Text = sprintf('Showing financial report...\nClick "Confirm" to exit.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManagerUIFigure = uifigure('Visible', 'off');
            app.ManagerUIFigure.Name = 'ManagerUI';
            app.ManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ManagerUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ManagerUIFigure);
            app.PutinTab = uitab(app.TabGroup);
            app.PutinTab.Title = 'Put in';
            app.BarcodeLabel = uilabel(app.PutinTab);
            app.BarcodeLabel.Text = '*Barcode';
            app.BarcodeEditField = uieditfield(app.PutinTab, 'numeric');
            app.BarcodeEditField.Limits = [0 Inf];
            app.BarcodeEditField.ValueDisplayFormat = '%.0f';
            app.NameEditFieldLabel = uilabel(app.PutinTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PutinTab, 'text');
            app.QuantityLabel = uilabel(app.PutinTab);
            app.QuantityLabel.Text = '*Quantity';
            app.QuantityEditField = uieditfield(app.PutinTab, 'numeric');
            app.QuantityEditField.Limits = [0 Inf];
            app.CostEditFieldLabel = uilabel(app.PutinTab);
            app.CostEditFieldLabel.Text = 'Cost';
            app.CostEditField = uieditfield(app.PutinTab, 'numeric');
            app.CostEditField.Limits = [0 Inf];
            app.PriceEditFieldLabel = uilabel(app.PutinTab);
            app.PriceEditFieldLabel.Text = 'Price';
            app.PriceEditField = uieditfield(app.PutinTab, 'numeric');
            app.PriceEditField.Limits = [0 Inf];
            app.UnitEditFieldLabel = uilabel(app.PutinTab);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.UnitEditField = uieditfield(app.PutinTab, 'text');
            app.PutinButton = uibutton(app.PutinTab, 'push');
            app.PutinButton.ButtonPushedFcn = createCallbackFcn(app, @PutinButtonPushed, true);
            app.PutinButton.Text = 'Put in';
            app.ClearButton = uibutton(app.PutinTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.EditQuantityTab = uitab(app.TabGroup);
            app.EditQuantityTab.Title = 'Edit Quantity';
            app.BarcodeLabel_2 = uilabel(app.EditQuantityTab);
            app.BarcodeLabel_2.Text = 'Barcode';
            app.BarcodeEditField_2 = uieditfield(app.EditQuantityTab, 'numeric');
            app.BarcodeEditField_2.Limits = [0 Inf];
            app.BarcodeEditField_2.ValueDisplayFormat = '%.0f';
            app.QuantityLabel_2 = uilabel(app.EditQuantityTab);
            app.QuantityLabel_2.Text = 'Quantity';
            app.QuantityEditField_2 = uieditfield(app.EditQuantityTab, 'numeric');
            app.QuantityEditField_2.ValueDisplayFormat = '%.0f';
            app.EditButton = uibutton(app.EditQuantityTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.EditInfoTab = uitab(app.TabGroup);
            app.EditInfoTab.Title = 'Edit Info';
            app.BarcodeEditField_3Label = uilabel(app.EditInfoTab);
            app.BarcodeEditField_3Label.Text = '*Barcode';
            app.BarcodeEditField_edit = uieditfield(app.EditInfoTab, 'numeric');
            app.BarcodeEditField_edit.Limits = [0 Inf];
            app.BarcodeEditField_edit.ValueDisplayFormat = '%.0f';
            app.NameEditField_2Label = uilabel(app.EditInfoTab);
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditField_edit = uieditfield(app.EditInfoTab, 'text');
            app.PriceEditField_2Label = uilabel(app.EditInfoTab);
            app.PriceEditField_2Label.Text = 'Price';
            app.PriceEditField_edit = uieditfield(app.EditInfoTab, 'numeric');
            app.PriceEditField_edit.Limits = [0 Inf];
            app.UnitEditField_2Label = uilabel(app.EditInfoTab);
            app.UnitEditField_2Label.Text = 'Unit';
            app.UnitEditField_edit = uieditfield(app.EditInfoTab, 'text');
            app.EditButton_edit = uibutton(app.EditInfoTab, 'push');
            app.EditButton_edit.ButtonPushedFcn = createCallbackFcn(app, @EditButton_editPushed, true);
            app.EditButton_edit.Text = 'Edit';
            app.ClearButton_edit = uibutton(app.EditInfoTab, 'push');
            app.ClearButton_edit.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_editPushed, true);
            app.ClearButton_edit.Text = 'Clear';
            app.GenerateReportTab = uitab(app.TabGroup);
            app.GenerateReportTab.Title = 'Generate Report';
            app.StartDateDatePickerLabel = uilabel(app.GenerateReportTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.GenerateReportTab);
            app.EndDateDatePickerLabel = uilabel(app.GenerateReportTab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.GenerateReportTab);
            app.TendencyChartButton = uibutton(app.GenerateReportTab, 'push');
            app.TendencyChartButton.ButtonPushedFcn = createCallbackFcn(app, @TendencyChartButtonPushed, true);
            app.TendencyChartButton.Text = 'Tendency Chart';
            app.FinancialReportButton = uibutton(app.GenerateReportTab, 'push');
            app.FinancialReportButton.ButtonPushedFcn = createCallbackFcn(app, @FinancialReportButtonPushed, true);
            app.FinancialReportButton.Text = 'Financial Report';
            app.BarcodeEditField_3Label_2 = uilabel(app.GenerateReportTab);
            app.BarcodeEditField_3Label_2.Text = 'Barcode';
            app.BarcodeEditField_genr = uieditfield(app.GenerateReportTab, 'numeric');
            app.BarcodeEditField_genr.Limits = [0 Inf];
            app.BarcodeEditField_genr.ValueDisplayFormat = '%.0f';
            app.MerchandiseTable = uitable(app.ManagerUIFigure);
            app.MerchandiseTable.ColumnName = {'Barcode'; 'Name'; 'Quantity'; 'Cost'; 'Price'; 'Unit'};
            app.MerchandiseTable.RowName = {};
            app.MerchandiseTable.ColumnSortable = true;
            app.MerchandiseTable.ColumnEditable = false;
            app.MerchandiseTable.CellSelectionCallback = createCallbackFcn(app, @MerchandiseTableCellSelection, true);
            app.MessagePanel = uipanel(app.ManagerUIFigure);
            app.MessagePanel.Title = 'Message';
            app.ConfirmButton = uibutton(app.MessagePanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.MessagePanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.MessageLabel = uilabel(app.MessagePanel);
            app.MessageLabel.Text = 'All ready.';
            app.RefreshStorageButton = uibutton(app.ManagerUIFigure, 'push');
            app.RefreshStorageButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshStorageButtonPushed, true);
            app.RefreshStorageButton.Text = 'Refresh Storage';
            app.chartPanel = uipanel(app.ManagerUIFigure);
            app.chartPanel.Visible = 'off';
            app.tendencyUIAxes = uiaxes(app.chartPanel);
            title(app.tendencyUIAxes, 'Title')
            xlabel(app.tendencyUIAxes, 'X')
            ylabel(app.tendencyUIAxes, 'Y')
            app.OKButton = uibutton(app.chartPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.quantityUIAxes = uiaxes(app.chartPanel);
            title(app.quantityUIAxes, '')
            xlabel(app.quantityUIAxes, 'X')
            ylabel(app.quantityUIAxes, 'Y')
            app.quantityUIAxes.YTick = [0 0.5 1];
            app.reportPanel = uipanel(app.ManagerUIFigure);
            app.reportPanel.Visible = 'off';
            app.reportLabel = uilabel(app.reportPanel);
            % Show the figure after all components are created
            app.ManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManagerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManagerUIFigure)
        end
    end
end