classdef PaymentUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CheckLabel            matlab.ui.control.Label
        ReceiveMoneyButton    matlab.ui.control.Button
        FindMoneyButton       matlab.ui.control.Button
        ReceiveLabel          matlab.ui.control.Label
        ReceiveEditField      matlab.ui.control.NumericEditField
        ChangeEditFieldLabel  matlab.ui.control.Label
        ChangeEditField       matlab.ui.control.NumericEditField
        TextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        cashierUI CashierUI
        money
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReceiveEditField
        function ReceiveEditFieldValueChanged(app, event)
            app.ReceiveMoneyButton.Enable = true;
        end

        % Button pushed function: ReceiveMoneyButton
        function ReceiveMoneyButtonPushed(app, event)
            if app.ReceiveEditField.Value - app.money >= 0
                app.ChangeEditField.Value = double(app.ReceiveEditField.Value - app.money);
                app.ReceiveMoneyButton.Enable = false;
                app.FindMoneyButton.Enable = true; 
            end
        end

        % Button pushed function: FindMoneyButton
        function FindMoneyButtonPushed(app, event)
            db = app.cashierUI.database;
            tableData = app.cashierUI.CheckTable.Data;
            
            for i = 1:size(tableData,1)
                db.editQuantity(str2double(tableData{i,1}),-tableData{i,3},MerchandiseEvent.sell);
            end
            
            tempCapp = CashierUI;
            tempCapp.database = db;
            delete(app.cashierUI);
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CheckLabel = uilabel(app.UIFigure);
            app.CheckLabel.Text = 'Check';
            app.ReceiveMoneyButton = uibutton(app.UIFigure, 'push');
            app.ReceiveMoneyButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveMoneyButtonPushed, true);
            app.ReceiveMoneyButton.Enable = 'off';
            app.ReceiveMoneyButton.Text = 'Receive Money';
            app.FindMoneyButton = uibutton(app.UIFigure, 'push');
            app.FindMoneyButton.ButtonPushedFcn = createCallbackFcn(app, @FindMoneyButtonPushed, true);
            app.FindMoneyButton.Enable = 'off';
            app.FindMoneyButton.Text = 'Find Money';
            app.ReceiveLabel = uilabel(app.UIFigure);
            app.ReceiveLabel.Text = 'Receive';
            app.ReceiveEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReceiveEditField.Limits = [0 Inf];
            app.ReceiveEditField.ValueDisplayFormat = '%.2f';
            app.ReceiveEditField.ValueChangedFcn = createCallbackFcn(app, @ReceiveEditFieldValueChanged, true);
            app.ChangeEditFieldLabel = uilabel(app.UIFigure);
            app.ChangeEditFieldLabel.Text = 'Change';
            app.ChangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChangeEditField.ValueDisplayFormat = '%.2f';
            app.ChangeEditField.Editable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PaymentUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end